classdef MuscleAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleAnalysisFigure           matlab.ui.Figure
        Muscle_image                   matlab.ui.control.UIAxes
        MuscleThickness_image          matlab.ui.control.UIAxes
        LongitudinalPlot               matlab.ui.control.UIAxes
        FileMenu                       matlab.ui.container.Menu
        NewVideoMenu                   matlab.ui.container.Menu
        OtherResultsMenu               matlab.ui.container.Menu
        BulkMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAllMenu                    matlab.ui.container.Menu
        SaveResultsMenu                matlab.ui.container.Menu
        SaveFramesMenu                 matlab.ui.container.Menu
        VideoMenu                      matlab.ui.container.Menu
        PlayMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        FasciaLimitsMenu               matlab.ui.container.Menu
        MiddlePointMenu                matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        SaveOptionsMenu                matlab.ui.container.Menu
        PathFolderMenu                 matlab.ui.container.Menu
        AlwaysPickaNewPathMenu         matlab.ui.container.Menu
        SameVideoFolderMenu            matlab.ui.container.Menu
        SaveAfterAnalysisMenu          matlab.ui.container.Menu
        AutomaticResultsMenu           matlab.ui.container.Menu
        AutomaticFramesMenu            matlab.ui.container.Menu
        AutomaticAllMenu               matlab.ui.container.Menu
        ManualMenu                     matlab.ui.container.Menu
        VideoPlayOptionsMenu           matlab.ui.container.Menu
        AutoplayMenu                   matlab.ui.container.Menu
        SummaryTextAreaLabel           matlab.ui.control.Label
        SummaryTextArea                matlab.ui.control.TextArea
        MeasurementyellowlinepixelSliderLabel  matlab.ui.control.Label
        MeasurementyellowlinepixelSlider  matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        MuscleThicknessVariationmmTextAreaLabel  matlab.ui.control.Label
        MuscleThicknessVariationmmTextArea  matlab.ui.control.TextArea
        MuscleThicknessMeanmmTextAreaLabel  matlab.ui.control.Label
        MuscleThicknessMeanmmTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        %Variables del video
        eco_matrix = [];
        eco_img_real_size = [];
        memoria_fascia_sup_inf = [];
        Results = [];
        Muscle_x_pixel = [];
        Value_MT = [];
        Muscle_frame = 1;
        
        %Variables del path
        path_file = '\';
        path_file_save = [];
    end
    
    methods (Access = private)
        
        function plot_eco_MT(app)
            %Plotear eco con la linea donde se realiza la medida y MT vs frame
            %eco
            imshow(app.eco_matrix(:,:,app.Muscle_frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
            hold(app.Muscle_image, 'on')
                plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,1)],'yo','LineWidth',3)
                plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,1)],'y-','LineWidth',3)
                if app.FasciaLimitsMenu.Checked
                    plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,1),'r:','LineWidth',2)
                    plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,2),'r:','LineWidth',2)
                end 
            hold(app.Muscle_image, 'off')
            
            %MT vs frame
            app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),app.Value_MT(:,3),'LineWidth',2) 
            hold(app.MuscleThickness_image, 'on')
                xline(app.MuscleThickness_image,app.Muscle_frame,'m-');
                xline(app.MuscleThickness_image, app.Results.AtRest_frames(2),'--','Color',[0.5,0.25,0]);
                xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(1),'--', 'Color',[0.5,0.25,0]);
                xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(2),'--', 'Color',[0.5,0.25,0]);
                plot(app.MuscleThickness_image,[app.Results.AtRest_frames(1) app.Results.UnderStimulation_frames(2)],[app.Results.AtRest_mean_mm app.Results.AtRest_mean_mm],'Color', [0.47,0.67,0.19]);
                %plot(app.MuscleThickness_image,[app.Results.AtRest_frames(2) app.Results.UnderStimulation_frames(2)],[app.Results.AtRest_mean_mm app.Results.AtRest_mean_mm],'--', 'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,[app.Results.AtRest_frames(1) app.Results.UnderStimulation_frames(2)],[app.Results.UnderStimulation_mean_mm app.Results.UnderStimulation_mean_mm], 'Color', [0.47,0.67,0.19]);
                %plot(app.MuscleThickness_image,[app.Results.AtRest_frames(1) app.Results.UnderStimulation_frames(1)],[app.Results.UnderStimulation_mean_mm app.Results.UnderStimulation_mean_mm],'--', 'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,ones(2,1) .* round(mean(app.Results.AtRest_frames)), [app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm],'k*');
                plot(app.MuscleThickness_image,ones(2,1) .* round(mean(app.Results.AtRest_frames)), [app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm],'k');
                text(app.MuscleThickness_image,round(mean(app.Results.AtRest_frames)),mean([app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm]),[num2str(app.Results.UnderStimulation_mean_mm - app.Results.AtRest_mean_mm) '[mm]']);
            hold(app.MuscleThickness_image, 'off')
            
            %MT vs longitudinal axis
            meanMTvslongitudinalaxis = mean(app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1));
            plot(app.LongitudinalPlot,(1:size(app.memoria_fascia_sup_inf, 2)) .* app.Results.Scale_factor, (app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1)),'LineWidth',2)
            hold(app.LongitudinalPlot, 'on')
                xline(app.LongitudinalPlot,app.Muscle_x_pixel * app.Results.Scale_factor,'y-.','LineWidth',2);
                yline(app.LongitudinalPlot, meanMTvslongitudinalaxis, '-', 'Color', [0.47,0.67,0.19])
            hold(app.LongitudinalPlot, 'off')
            
            app.MuscleThicknessMeanmmTextArea.Value = num2str(meanMTvslongitudinalaxis);
            
        end
        
        
        function SummaryTextDisplay(app)
            app.SummaryTextArea.Value = {  ['Video Name: ', app.Results.Name] ...
                             , '' ...
                             , ['Duration [s]: ', num2str(app.Results.Duration)] ...
                             , ['Frame Rate [fps]: ', num2str(app.Results.Frame_rate)] ...
                             , ['Scale Factor [mm/pixel]: ', num2str(app.Results.Scale_factor)] ...
                             , '' ...
                             , ['Image center [pixel]: ', num2str(app.Results.Muscle_longitudinal_middle_point)] ...
                             , ['Measurement (yellow line) [pixel]: ', num2str(app.Results.Muscle_x_pixel)] ...
                             , '' ...
                             , 'At Rest:' ...
                             , ['- Start Frame: ', num2str(app.Results.AtRest_frames(1))] ...
                             , ['- End Frame: ', num2str(app.Results.AtRest_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.AtRest_duration_s)] ...
                             , ['- Mean [mm]: ', num2str(app.Results.AtRest_mean_mm)] ...
                             , ['- Variance [mm^2]: ', num2str(app.Results.AtRest_variance_mm2)]...
                             , '' ...
                             , 'Rise:' ...
                             , ['-Start Frame: ', num2str(app.Results.Rise_frames(1))] ...
                             , ['-End Frame: ', num2str(app.Results.Rise_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.Rise_duration_s)] ...
                             , '' ...
                             , 'Under Stimulation:' ...
                             , ['- Start Frame: ', num2str(app.Results.UnderStimulation_frames(1))] ...
                             , ['- End Frame: ', num2str(app.Results.UnderStimulation_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.UnderStimulation_duration_s)] ...
                             , ['- Mean [mm]: ', num2str(app.Results.UnderStimulation_mean_mm)] ...
                             , ['- Variance [mm^2]: ', num2str(app.Results.UnderStimulation_variance_mm2)]};
                         
            %Mucle Thickness Variation
            app.MuscleThicknessVariationmmTextArea.Value = num2str(app.Results.UnderStimulation_mean_mm - app.Results.AtRest_mean_mm);
            app.MuscleThicknessMeanmmTextArea.Value = num2str(mean(app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1)));
        end


        function PlotEcoPlay(app)
            %Animación del funcionamiento del sistema
            app.VideoMenu.Enable = false;
            app.MeasurementyellowlinepixelSlider.Enable = 0;
            app.FrameSlider.Enable = 0;
            Pause_t = 0.5/app.Results.Frame_rate;
            Value_MT_plot = app.Value_MT(:,3);
            for frame = 1:size(app.eco_matrix,3)
                app.FrameSlider.Value = frame;
                imshow(app.eco_matrix(:,:,frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
                hold(app.Muscle_image, 'on')
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'yo','LineWidth',3)
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'y-','LineWidth',3)
                    if app.FasciaLimitsMenu.Checked
                        plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,1),'r:','LineWidth',2)
                        plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,2),'r:','LineWidth',2)
                    end
                hold(app.Muscle_image, 'off')
                
                plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),Value_MT_plot,'LineWidth',2) 
                hold(app.MuscleThickness_image, 'on')
                    xline(app.MuscleThickness_image,frame,'m-');
                    xline(app.MuscleThickness_image, app.Results.AtRest_frames(2),'--','Color',[0.5,0.25,0]);
                    xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(1),'--', 'Color',[0.5,0.25,0]);
                    xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(2),'--', 'Color',[0.5,0.25,0]);
                hold(app.MuscleThickness_image, 'off')
                
                %MT vs longitudinal axis
                plot(app.LongitudinalPlot,(1:size(app.memoria_fascia_sup_inf, 2)) .* app.Results.Scale_factor, (app.memoria_fascia_sup_inf(frame,:,2) - app.memoria_fascia_sup_inf(frame,:,1)),'LineWidth',2)
                hold(app.LongitudinalPlot, 'on')
                    xline(app.LongitudinalPlot,app.Muscle_x_pixel * app.Results.Scale_factor,'y-.','LineWidth',2);
                hold(app.LongitudinalPlot, 'off')
                pause(Pause_t)
                
                app.MuscleThicknessMeanmmTextArea.Value = num2str(mean(app.memoria_fascia_sup_inf(frame,:,2) - app.memoria_fascia_sup_inf(frame,:,1)));
            end 
            app.MeasurementyellowlinepixelSlider.Enable = 1;
            app.FrameSlider.Enable = 1;
            app.Muscle_frame = frame;
            app.VideoMenu.Enable = true;
            app.plot_eco_MT
        end
        
        function UpdatePaht(app, path)
            %Update the path variable
            app.path_file = path;
        end
        
        function SaveOptions(app)
            %Check wich save option is selected
            if app.AutomaticResultsMenu.Checked
                app.SaveResultsFunction();
            elseif app.AutomaticFramesMenu.Checked
                app.SaveFramesFunction();
            elseif app.AutomaticAllMenu.Checked
                app.SaveResultsFunction();
                app.SaveFramesFunction();
            end
        end
        
        function SaveFramesFunction(app)
            if app.SameVideoFolderMenu.Checked
                path_frames = app.Results.Folder; % Saves Frames on same folder as selected video 
            else
                path_frames = app.path_file_save;
            end
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_frames == 0
                return
            end
            SaveFrames(app.eco_matrix,app.Results.Muscle_longitudinal_middle_point, app.Results.Name,path_frames, app.Results.Scale_factor);
            drawnow;
            figure(app.MuscleAnalysisFigure)
        end
        
        function SaveResultsFunction(app)
            if app.SameVideoFolderMenu.Checked
                path_results = app.Results.Folder; % Saves Frames on same folder as selected video 
            else
                path_results = app.path_file_save;
            end
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_results == 0
                return
            end
            SaveResults(app.memoria_fascia_sup_inf, app.eco_matrix, app.Results, app.Value_MT, app.Results.Name, path_results);
            drawnow;
            figure(app.MuscleAnalysisFigure)
            % Store snapshot Analizar como funciona con las opciones de
            % guardado
            %path_results = strcat(path_results,'/',app.Results.Name,'_results');
            %exportapp(app.MuscleAnalysisFigure, fullfile(path_results,strcat(app.Results.Name,'.png')))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.MuscleAnalysisFigure.WindowState = 'maximized';
            addpath(strcat(pwd,'\Functions'));
        end

        % Value changed function: MeasurementyellowlinepixelSlider
        function MeasurementyellowlinepixelSliderValueChanged(app, event)
            value = app.MeasurementyellowlinepixelSlider.Value;
            app.Muscle_x_pixel = round(value);
            app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel; 
            app.Results.Muscle_x_pixel = app.MeasurementyellowlinepixelSlider.Value;
            app.Results.AtRest_mean_mm = mean((app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,1)));
            app.Results.AtRest_variance_mm2 = std((app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,1))) ^ 2;
            app.Results.UnderStimulation_mean_mm = mean((app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,1)));
            app.Results.UnderStimulation_variance_mm2 = std((app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,1))) ^ 2;
            if app.MeasurementyellowlinepixelSlider.Value == app.Results.Muscle_longitudinal_middle_point
                app.MiddlePointMenu.Checked = true;
            else
                app.MiddlePointMenu.Checked = false;
            end
            app.SummaryTextDisplay
            app.plot_eco_MT
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            app.Muscle_frame = round(value);
            app.FrameSlider.Value = app.Muscle_frame;
            app.plot_eco_MT
        end

        % Menu selected function: OtherResultsMenu
        function OtherResultsMenuSelected(app, event)
            path_v = uigetdir(app.path_file, 'Select Results Folder');
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_v == 0
                return
            end
            app.UpdatePaht(path_v)
            app.eco_matrix = load(strcat(path_v,'\eco_memory.mat'));
            app.eco_matrix = app.eco_matrix.eco_memory;
            app.memoria_fascia_sup_inf = load(strcat(path_v,'\memoria_fascia_sup_inf.mat'));
            app.memoria_fascia_sup_inf = app.memoria_fascia_sup_inf.memoria_fascia_sup_inf;
            app.Results = load(strcat(path_v,'\Results.mat'));
            app.Results = app.Results.Results;
            app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MeasurementyellowlinepixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.plot_eco_MT
            app.Value_MT(:,1) = 1:size(app.memoria_fascia_sup_inf,1);
            app.Value_MT(:,2) = app.Value_MT(:,1) / app.Results.Frame_rate;
            app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            app.SummaryTextDisplay
            app.MeasurementyellowlinepixelSlider.Enable = true;
            app.FrameSlider.Enable = true;
            app.VideoMenu.Enable = true;
            app.SaveMenu.Enable = true;
            app.ViewMenu.Enable =true;
        end

        % Menu selected function: SaveFramesMenu
        function SaveFramesMenuSelected(app, event)
            if app.AlwaysPickaNewPathMenu.Checked
                app.path_file_save = uigetdir(app.path_file, 'Select Folder to Save Frames Folder');
                drawnow;
            end
            figure(app.MuscleAnalysisFigure)
            if app.path_file_save == 0
                return
            end
            app.UpdatePaht(app.path_file_save)
            app.SaveFramesFunction();
        end

        % Menu selected function: SaveResultsMenu
        function SaveResultsMenuSelected(app, event)
            if app.AlwaysPickaNewPathMenu.Checked
                app.path_file_save = uigetdir(app.path_file, 'Select Folder to Save Results Folder');
                drawnow;
            end
            figure(app.MuscleAnalysisFigure)
            if app.path_file_save == 0
                return
            end
            app.UpdatePaht(app.path_file_save)
            app.SaveResultsFunction();
        end

        % Menu selected function: SaveAllMenu
        function SaveAllMenuSelected(app, event)
            if app.AlwaysPickaNewPathMenu.Checked
                app.path_file_save = uigetdir(app.path_file, 'Select Folder to Save Results and Frames Folder');
                drawnow;
            end
            figure(app.MuscleAnalysisFigure)
            if app.path_file_save == 0
                return
            end
            app.UpdatePaht(app.path_file_save)
            app.SaveResultsFunction();
            app.SaveFramesFunction();
        end

        % Menu selected function: MiddlePointMenu
        function MiddlePointMenuSelected(app, event)
            app.MiddlePointMenu.Checked = true;
            app.MeasurementyellowlinepixelSlider.Value = app.Results.Muscle_longitudinal_middle_point;
            app.MeasurementyellowlinepixelSliderValueChanged();
        end

        % Menu selected function: NewVideoMenu
        function NewVideoMenuSelected(app, event)
            [video_name, path_v] = uigetfile( ...
                {'*.avi', '*.avi'; ...
                '*.mp4', '*.mp4'; ...
                '*.mp4; *.avi', 'All Video Files (*.mp4, *.avi)'}, ...
                'Select Video File', ...
                app.path_file, ...
                'MultiSelect', 'on');
            drawnow; % Update figures and process callbacks
            figure(app.MuscleAnalysisFigure)
            %[video_name,path_v] = uigetfile('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\DAY 1\','*.*','Select Video File');
            %path_v=uigetdir('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\','Select DAY Folder to Analize ALL AVI files');
            if path_v == 0
                return
            end
            app.UpdatePaht(path_v)
            
            
            % Automatically process all AVI in folder and all of the subfolders
            %cd (path_v);
            %d=dir('**/*.avi');
            %for i=1:length(d)
            %    fn=d(i).name;
            %    ff=strcat(d(i).folder,'\');
            video_name = string(video_name);
            len_video_name = length(video_name);
            if (len_video_name > 1) && (app.ManualMenu.Checked)
                %For the process in bulk option.
                %In the case that the Manual for saving option is checked 
                %the program suggests changing to the Automatic option. 
                answer = questdlg({'Do you want to change your Save Settings?','More save options: Settings > Save Options > Save After Analysis'}, ...
	            'Save Settings', ...
	            'Automatic (Results)','Automatic (Frames)', 'Keep Manual', 'Automatic (Results)');
                switch answer
                    case 'Automatic (Results)'
                        app.AutomaticResultsMenuSelected;
                    case 'Automatic (Frames)'
                        app.AutomaticFramesMenuSelected;
                    case 'Keep Manual'
                        
                    case ''
                        return
                end
            end
            
            if ~app.ManualMenu.Checked && app.AlwaysPickaNewPathMenu.Checked
                app.path_file_save = uigetdir(app.path_file,'Select Folder to Save Results Folder');
                app.UpdatePaht(app.path_file_save);
            end
            
            drawnow; % Update figures and process callbacks
            figure(app.MuscleAnalysisFigure)
            
            for k = 1:len_video_name   
                [app.eco_matrix, app.memoria_fascia_sup_inf, app.Results] = Measure(video_name(k),path_v); %processing w/ dynamic file name and folder
                app.Value_MT = [];
                app.Muscle_frame = 1;        
                app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
                app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
                app.MeasurementyellowlinepixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
                app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel;
                app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
                app.FrameSlider.Value = app.Muscle_frame;
                app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
                app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
                app.Value_MT(:,1) = 1:size(app.memoria_fascia_sup_inf,1);
                app.Value_MT(:,2) = app.Value_MT(:,1) / app.Results.Frame_rate;
                app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
                app.SummaryTextDisplay
                if app.AutoplayMenu.Checked
                    app.PlotEcoPlay
                else
                    app.plot_eco_MT();
                end
                drawnow; % Update figures and process callbacks
                figure(app.MuscleAnalysisFigure)
                app.SaveOptions();
                
            end
            app.SaveMenu.Enable = true;  
            app.ViewMenu.Enable = true;
            app.VideoMenu.Enable = true;
            app.MeasurementyellowlinepixelSlider.Enable = true;
            app.FrameSlider.Enable = true;
        end

        % Menu selected function: PlayMenu
        function PlayMenuSelected(app, event)
            app.PlotEcoPlay
        end

        % Menu selected function: FasciaLimitsMenu
        function FasciaLimitsMenuSelected(app, event)
            app.FasciaLimitsMenu.Checked = ~app.FasciaLimitsMenu.Checked;
            app.plot_eco_MT();
        end

        % Menu selected function: AutoplayMenu
        function AutoplayMenuSelected(app, event)
            app.AutoplayMenu.Checked = ~app.AutoplayMenu.Checked;
        end

        % Menu selected function: AlwaysPickaNewPathMenu
        function AlwaysPickaNewPathMenuSelected(app, event)
            app.AlwaysPickaNewPathMenu.Checked = true;
            app.SameVideoFolderMenu.Checked = false;
        end

        % Menu selected function: SameVideoFolderMenu
        function SameVideoFolderMenuSelected(app, event)
            app.AlwaysPickaNewPathMenu.Checked = false;
            app.SameVideoFolderMenu.Checked = true;
        end

        % Menu selected function: AutomaticResultsMenu
        function AutomaticResultsMenuSelected(app, event)
            app.AutomaticResultsMenu.Checked = true;
            app.AutomaticFramesMenu.Checked = false;
            app.AutomaticAllMenu.Checked = false;
            app.ManualMenu.Checked = false;
        end

        % Menu selected function: AutomaticFramesMenu
        function AutomaticFramesMenuSelected(app, event)
            app.AutomaticResultsMenu.Checked = false;
            app.AutomaticFramesMenu.Checked = true;
            app.AutomaticAllMenu.Checked = false;
            app.ManualMenu.Checked = false;
        end

        % Menu selected function: AutomaticAllMenu
        function AutomaticAllMenuSelected(app, event)
            app.AutomaticResultsMenu.Checked = false;
            app.AutomaticFramesMenu.Checked = false;
            app.AutomaticAllMenu.Checked = true;
            app.ManualMenu.Checked = false;
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            app.AutomaticResultsMenu.Checked = false;
            app.AutomaticFramesMenu.Checked = false;
            app.AutomaticAllMenu.Checked = false;
            app.ManualMenu.Checked = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleAnalysisFigure = uifigure('Visible', 'off');
            app.MuscleAnalysisFigure.Name = 'Muscle Analysis App';
            app.Muscle_image = uiaxes(app.MuscleAnalysisFigure);
            title(app.Muscle_image, 'Muscle')
            xlabel(app.Muscle_image, '[mm]')
            ylabel(app.Muscle_image, '[mm]')
            app.MuscleThickness_image = uiaxes(app.MuscleAnalysisFigure);
            title(app.MuscleThickness_image, 'Muscle Thickness')
            xlabel(app.MuscleThickness_image, 'Frame')
            ylabel(app.MuscleThickness_image, '[mm]')
            app.LongitudinalPlot = uiaxes(app.MuscleAnalysisFigure);
            title(app.LongitudinalPlot, 'Longitudinal Axis')
            xlabel(app.LongitudinalPlot, 'Longitudinal Axis [mm]')
            ylabel(app.LongitudinalPlot, 'Muscle Thickness [mm]')
            app.FileMenu = uimenu(app.MuscleAnalysisFigure);
            app.FileMenu.Text = 'File';
            app.NewVideoMenu = uimenu(app.FileMenu);
            app.NewVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @NewVideoMenuSelected, true);
            app.NewVideoMenu.Text = 'New Video';
            app.OtherResultsMenu = uimenu(app.FileMenu);
            app.OtherResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @OtherResultsMenuSelected, true);
            app.OtherResultsMenu.Text = 'Other Results';
            app.BulkMenu = uimenu(app.FileMenu);
            app.BulkMenu.Text = 'Bulk';
            app.SaveMenu = uimenu(app.MuscleAnalysisFigure);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SaveAllMenu = uimenu(app.SaveMenu);
            app.SaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllMenuSelected, true);
            app.SaveAllMenu.Text = 'Save All';
            app.SaveResultsMenu = uimenu(app.SaveMenu);
            app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultsMenuSelected, true);
            app.SaveResultsMenu.Text = 'Save Results';
            app.SaveFramesMenu = uimenu(app.SaveMenu);
            app.SaveFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFramesMenuSelected, true);
            app.SaveFramesMenu.Text = 'Save Frames';
            app.VideoMenu = uimenu(app.MuscleAnalysisFigure);
            app.VideoMenu.Enable = 'off';
            app.VideoMenu.Text = 'Video';
            app.PlayMenu = uimenu(app.VideoMenu);
            app.PlayMenu.MenuSelectedFcn = createCallbackFcn(app, @PlayMenuSelected, true);
            app.PlayMenu.Text = 'Play';
            app.ViewMenu = uimenu(app.MuscleAnalysisFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = 'View';
            app.FasciaLimitsMenu = uimenu(app.ViewMenu);
            app.FasciaLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @FasciaLimitsMenuSelected, true);
            app.FasciaLimitsMenu.Checked = 'on';
            app.FasciaLimitsMenu.Text = 'Fascia Limits';
            app.MiddlePointMenu = uimenu(app.ViewMenu);
            app.MiddlePointMenu.MenuSelectedFcn = createCallbackFcn(app, @MiddlePointMenuSelected, true);
            app.MiddlePointMenu.Text = 'Middle Point';
            app.SettingsMenu = uimenu(app.MuscleAnalysisFigure);
            app.SettingsMenu.Text = 'Settings';
            app.SaveOptionsMenu = uimenu(app.SettingsMenu);
            app.SaveOptionsMenu.Text = 'Save Options';
            app.PathFolderMenu = uimenu(app.SaveOptionsMenu);
            app.PathFolderMenu.Text = 'Path Folder';
            app.AlwaysPickaNewPathMenu = uimenu(app.PathFolderMenu);
            app.AlwaysPickaNewPathMenu.MenuSelectedFcn = createCallbackFcn(app, @AlwaysPickaNewPathMenuSelected, true);
            app.AlwaysPickaNewPathMenu.Checked = 'on';
            app.AlwaysPickaNewPathMenu.Text = 'Always Pick a New Path';
            app.SameVideoFolderMenu = uimenu(app.PathFolderMenu);
            app.SameVideoFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SameVideoFolderMenuSelected, true);
            app.SameVideoFolderMenu.Text = 'Same Video Folder';
            app.SaveAfterAnalysisMenu = uimenu(app.SaveOptionsMenu);
            app.SaveAfterAnalysisMenu.Checked = 'on';
            app.SaveAfterAnalysisMenu.Text = 'Save After Analysis';
            app.AutomaticResultsMenu = uimenu(app.SaveAfterAnalysisMenu);
            app.AutomaticResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticResultsMenuSelected, true);
            app.AutomaticResultsMenu.Text = 'Automatic (Results)';
            app.AutomaticFramesMenu = uimenu(app.SaveAfterAnalysisMenu);
            app.AutomaticFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticFramesMenuSelected, true);
            app.AutomaticFramesMenu.Text = 'Automatic  (Frames)';
            app.AutomaticAllMenu = uimenu(app.SaveAfterAnalysisMenu);
            app.AutomaticAllMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticAllMenuSelected, true);
            app.AutomaticAllMenu.Text = 'Automatic (All)';
            app.ManualMenu = uimenu(app.SaveAfterAnalysisMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Checked = 'on';
            app.ManualMenu.Text = 'Manual';
            app.VideoPlayOptionsMenu = uimenu(app.SettingsMenu);
            app.VideoPlayOptionsMenu.Text = 'Video Play Options';
            app.AutoplayMenu = uimenu(app.VideoPlayOptionsMenu);
            app.AutoplayMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoplayMenuSelected, true);
            app.AutoplayMenu.Text = 'Autoplay';
            app.SummaryTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.SummaryTextArea.Editable = 'off';
            app.MeasurementyellowlinepixelSliderLabel = uilabel(app.MuscleAnalysisFigure);
            app.MeasurementyellowlinepixelSliderLabel.Text = 'Measurement (yellow line) [pixel]';
            app.MeasurementyellowlinepixelSlider = uislider(app.MuscleAnalysisFigure);
            app.MeasurementyellowlinepixelSlider.ValueChangedFcn = createCallbackFcn(app, @MeasurementyellowlinepixelSliderValueChanged, true);
            app.MeasurementyellowlinepixelSlider.Enable = 'off';
            app.FrameSliderLabel = uilabel(app.MuscleAnalysisFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.MuscleAnalysisFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.MuscleThicknessVariationmmTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.MuscleThicknessVariationmmTextAreaLabel.Text = 'Muscle Thickness Variation [mm]: ';
            app.MuscleThicknessVariationmmTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.MuscleThicknessVariationmmTextArea.Editable = 'off';
            app.MuscleThicknessMeanmmTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.MuscleThicknessMeanmmTextAreaLabel.Text = 'Muscle Thickness Mean [mm]: ';
            app.MuscleThicknessMeanmmTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.MuscleThicknessMeanmmTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.MuscleAnalysisFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuscleAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleAnalysisFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleAnalysisFigure)
        end
    end
end